classdef GCI_Ctrl_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        NALabel_9               matlab.ui.control.Label
        NALabel_10              matlab.ui.control.Label
        NALabel_8               matlab.ui.control.Label
        NALabel_6               matlab.ui.control.Label
        TimeScope3              matlab.ui.scope.TimeScope
        NALabel_4               matlab.ui.control.Label
        SimulationControls      simulink.ui.control.SimulationControls
        ALabel                  matlab.ui.control.Label
        NALabel_3               matlab.ui.control.Label
        I_rmsLabel              matlab.ui.control.Label
        VLabel_2                matlab.ui.control.Label
        NALabel_2               matlab.ui.control.Label
        V_acrmsLabel            matlab.ui.control.Label
        TimeScope2              matlab.ui.scope.TimeScope
        VLabel                  matlab.ui.control.Label
        V_dcLabel_2             matlab.ui.control.Label
        TimeScope               matlab.ui.scope.TimeScope
        Switch                  matlab.ui.control.Switch
        IqEditField             matlab.ui.control.NumericEditField
        IqEditFieldLabel        matlab.ui.control.Label
        IdEditField             matlab.ui.control.NumericEditField
        IdEditFieldLabel        matlab.ui.control.Label
        QEditField              matlab.ui.control.NumericEditField
        QEditFieldLabel         matlab.ui.control.Label
        PEditField              matlab.ui.control.NumericEditField
        PEditFieldLabel         matlab.ui.control.Label
        SimulationProgress      simulink.ui.control.SimulationProgress
        ModeRequestButtonGroup  matlab.ui.container.ButtonGroup
        PowerButton             matlab.ui.control.RadioButton
        StandbyButton           matlab.ui.control.RadioButton
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = public)
        Idsp  % Description
    end
    
    properties (Access = private)
        UpdateTimer   % Timer object
    end
    
    methods (Access = private)
        
        function updateLabel(app)
            try
                % currentVal = evalin('base', 'IdSp.Value');  % or any variable
                % app.NALabel.Text = sprintf('K = %.2f', currentVal);
                currentVal = evalin('base', 'Ctrl.IdSp');  % or any variable
                app.IdEditField.Value = currentVal ;
                currentVal = evalin('base', 'Ctrl.IqSp');  % or any variable
                app.IqEditField.Value = currentVal ;
                currentVal = evalin('base', 'Ctrl.PSp');  % or any variable
                app.PEditField.Value = currentVal ;
                currentVal = evalin('base', 'Ctrl.QSp');  % or any variable
                app.QEditField.Value = currentVal ;
                currentVal = evalin('base', 'Ctrl.ModReq');  % or any variable
                switch currentVal 
                    case "Standby"
                        app.PowerButton.Value= 0;
                        app.StandbyButton.Value= 1;
                    case "Power"
                        app.PowerButton.Value= 1;
                        app.StandbyButton.Value= 0;
                end
                currentVal = evalin('base', 'Par.CurCont.PorISel');  % or any variable
                switch currentVal 
                    case 0 
                        app.Switch.Value = 'PQ Cntrl';
                    case 1
                        app.Switch.Value = 'Idq Cntrl';
                end   
                
            catch ME
                app.NALabel.Text = 'Error reading K';
            end
        end
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.UpdateTimer = timer(...
            'ExecutionMode', 'fixedRate', ...
            'Period', 1, ...                 % update every 1 second
            'TimerFcn', @(~,~) app.updateLabel());
        
            start(app.UpdateTimer);
        end

        % Value changed function: IdEditField
        function IdEditFieldValueChanged(app, event)
            newVal = app.IdEditField.Value;
            cmd = sprintf('Ctrl.IdSp = (%g);', newVal);
            % Send the command to base workspace
            evalin('base', cmd);
            set_param(bdroot, 'SimulationCommand', 'update');
        end

        % Value changed function: IqEditField
        function IqEditFieldValueChanged(app, event)
            newVal = app.IqEditField.Value;
            cmd = sprintf('Ctrl.IqSp = (%g);', newVal);
            % Send the command to base workspace
            evalin('base', cmd);
            set_param(bdroot, 'SimulationCommand', 'update');
        end

        % Value changed function: PEditField
        function PEditFieldValueChanged(app, event)
            newVal = app.PEditField.Value;
            cmd = sprintf('Ctrl.PSp = (%g);', newVal);
            % Send the command to base workspace
            evalin('base', cmd);
            set_param(bdroot, 'SimulationCommand', 'update');
        end

        % Value changed function: QEditField
        function QEditFieldValueChanged(app, event)
            newVal = app.QEditField.Value;
            cmd = sprintf('Ctrl.QSp = (%g);', newVal);
            % Send the command to base workspace
            evalin('base', cmd);
            set_param(bdroot, 'SimulationCommand', 'update');
        end

        % Selection changed function: ModeRequestButtonGroup
        function ModeRequestButtonGroupSelectionChanged(app, event)
            newVal = app.ModeRequestButtonGroup.SelectedObject.Text;
            cmd = sprintf('Ctrl.ModReq = "%s";', newVal);
            % Send the command to base workspace
            evalin('base', cmd);
            set_param(bdroot, 'SimulationCommand', 'update');
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            newVal = app.Switch.Value;
            switch newVal 
                case 'PQ Cntrl'
                  cmd = 'Ctrl.PorISel = 0';
                case 'Idq Cntrl'
                  cmd = 'Ctrl.PorISel = 1';
            end
            evalin('base', cmd);
            set_param(bdroot, 'SimulationCommand', 'update');
        end

        % Callback function
        function SimulationControlsSizeChanged(app, event)
            position = app.SimulationControls.Position;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ModeRequestButtonGroup = uibuttongroup(app.GridLayout);
            app.ModeRequestButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeRequestButtonGroupSelectionChanged, true);
            app.ModeRequestButtonGroup.Title = 'Mode Request';
            app.StandbyButton = uiradiobutton(app.ModeRequestButtonGroup);
            app.StandbyButton.Text = 'Standby';
            app.StandbyButton.Value = true;
            app.PowerButton = uiradiobutton(app.ModeRequestButtonGroup);
            app.PowerButton.Text = 'Power';
            app.SimulationProgress = uisimprogress(app.GridLayout);
            app.SimulationProgress.Simulation = app.Simulation;
            app.PEditFieldLabel = uilabel(app.GridLayout);
            app.PEditFieldLabel.Text = 'P*';
            app.PEditField = uieditfield(app.GridLayout, 'numeric');
            app.PEditField.Limits = [-5000 5000];
            app.PEditField.ValueChangedFcn = createCallbackFcn(app, @PEditFieldValueChanged, true);
            app.QEditFieldLabel = uilabel(app.GridLayout);
            app.QEditFieldLabel.Text = 'Q*';
            app.QEditField = uieditfield(app.GridLayout, 'numeric');
            app.QEditField.Limits = [-5000 5000];
            app.QEditField.ValueChangedFcn = createCallbackFcn(app, @QEditFieldValueChanged, true);
            app.IdEditFieldLabel = uilabel(app.GridLayout);
            app.IdEditFieldLabel.Text = 'Id*';
            app.IdEditField = uieditfield(app.GridLayout, 'numeric');
            app.IdEditField.Limits = [-50 50];
            app.IdEditField.ValueChangedFcn = createCallbackFcn(app, @IdEditFieldValueChanged, true);
            app.IqEditFieldLabel = uilabel(app.GridLayout);
            app.IqEditFieldLabel.Text = 'Iq*';
            app.IqEditField = uieditfield(app.GridLayout, 'numeric');
            app.IqEditField.Limits = [-50 50];
            app.IqEditField.ValueChangedFcn = createCallbackFcn(app, @IqEditFieldValueChanged, true);
            app.Switch = uiswitch(app.GridLayout, 'slider');
            app.Switch.Items = {'PQ Cntrl', 'Idq Cntrl'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'PQ Cntrl';
            app.TimeScope = uitimescope(app.GridLayout);
            app.TimeScope.LegendVisible = 'on';
            app.TimeScope.YLabel = 'A';
            app.TimeScope.XTimeSpan = 5;
            app.V_dcLabel_2 = uilabel(app.GridLayout);
            app.V_dcLabel_2.Interpreter = 'latex';
            app.V_dcLabel_2.Text = 'V_{dc}';
            app.VLabel = uilabel(app.GridLayout);
            app.VLabel.Text = 'V';
            app.TimeScope2 = uitimescope(app.GridLayout);
            app.TimeScope2.LegendNames = {'C2000_SW/Rate Transition1:1'};
            app.TimeScope2.Title = 'C2000_SW/Rate Transition1:1';
            app.TimeScope2.YLabel = 'rad/s';
            app.TimeScope2.XTimeSpan = 5;
            app.V_acrmsLabel = uilabel(app.GridLayout);
            app.V_acrmsLabel.Interpreter = 'latex';
            app.V_acrmsLabel.Text = 'V_{ac-rms}';
            app.NALabel_2 = uilabel(app.GridLayout);
            app.NALabel_2.Interpreter = 'latex';
            app.NALabel_2.Text = 'N/A';
            app.VLabel_2 = uilabel(app.GridLayout);
            app.VLabel_2.Text = 'V';
            app.I_rmsLabel = uilabel(app.GridLayout);
            app.I_rmsLabel.Interpreter = 'latex';
            app.I_rmsLabel.Text = 'I_{rms}';
            app.NALabel_3 = uilabel(app.GridLayout);
            app.NALabel_3.Interpreter = 'latex';
            app.NALabel_3.Text = 'N/A';
            app.ALabel = uilabel(app.GridLayout);
            app.ALabel.Text = 'A';
            app.SimulationControls = uisimcontrols(app.GridLayout);
            app.SimulationControls.Simulation = app.Simulation;
            app.NALabel_4 = uilabel(app.GridLayout);
            app.NALabel_4.Interpreter = 'latex';
            app.NALabel_4.Text = 'N/A';
            app.TimeScope3 = uitimescope(app.GridLayout);
            app.TimeScope3.YLabel = 'V';
            app.NALabel_6 = uilabel(app.GridLayout);
            app.NALabel_6.Text = 'N/A';
            app.NALabel_8 = uilabel(app.GridLayout);
            app.NALabel_8.Text = 'N/A';
            app.NALabel_10 = uilabel(app.GridLayout);
            app.NALabel_10.Text = 'N/A';
            app.NALabel_9 = uilabel(app.GridLayout);
            app.NALabel_9.Text = 'N/A';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/C2000_SW/Rate Transition:1'], app.NALabel_4, 'Text');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/C2000_SW/Rate Transition2:1'], app.NALabel_2, 'Text');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/C2000_SW/Rate Transition3:1'], app.NALabel_3, 'Text');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/C2000_SW/Rate Transition1:1'], app.TimeScope2);
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GCI_Ctrl_app

            % Associate the Simulink Model
            app.Simulation = simulation('GCI_Ctrl_PLL_C2000');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end